classdef FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EndEffectorLabel      matlab.ui.control.Label
        ZEditField            matlab.ui.control.NumericEditField
        ZEditFieldLabel       matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        KeySTheta3Label       matlab.ui.control.Label
        KeyATheta3Label       matlab.ui.control.Label
        KeyETheta2Label       matlab.ui.control.Label
        KeyRTheta2Label       matlab.ui.control.Label
        KeyWTheta1Label       matlab.ui.control.Label
        KeyQTheta1Label       matlab.ui.control.Label
        Theta3EditField       matlab.ui.control.NumericEditField
        Theta3EditFieldLabel  matlab.ui.control.Label
        Theta2EditField       matlab.ui.control.NumericEditField
        Theta2EditFieldLabel  matlab.ui.control.Label
        Theta1EditField       matlab.ui.control.NumericEditField
        Theta1EditFieldLabel  matlab.ui.control.Label
        ConnectionLamp        matlab.ui.control.Lamp
        ConnectionLampLabel   matlab.ui.control.Label
        MODEDropDown          matlab.ui.control.DropDown
        MODEDropDownLabel     matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s
    end
    methods (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM9", 9600);
            configureTerminator(app.s,"LF") ;
            app.ConnectionLamp.Color = "green";
            
            app.Theta1EditField.Value = 0;
            app.Theta2EditField.Value = 0;
            app.Theta3EditField.Value = 0;
            
            theta1 = string(app.Theta1EditField.Value+150);
            theta2 = string(app.Theta2EditField.Value+60);
            theta3 = string(app.Theta3EditField.Value+150);
            z = strcat('<', theta1, ", ", theta2, ", ", theta3, ">");
            writeline(app.s, z);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'q'
                    if app.Theta1EditField.Value <= 149
                    app.Theta1EditField.Value = app.Theta1EditField.Value+1;
                    end
                case 'w'
                    if app.Theta1EditField.Value >= -149
                    app.Theta1EditField.Value = app.Theta1EditField.Value-1;
                    end
                case 'e'
                    if app.Theta2EditField.Value <= 149
                        app.Theta2EditField.Value = app.Theta2EditField.Value+1;
                    end
                case 'r'
                    if app.Theta2EditField.Value >= -149
                        app.Theta2EditField.Value = app.Theta2EditField.Value-1;
                    end
                case 'a'
                    if app.Theta3EditField.Value <= 149
                        app.Theta3EditField.Value = app.Theta3EditField.Value+1;
                    end
                case 's'
                    if app.Theta3EditField.Value >= -149
                        app.Theta3EditField.Value = app.Theta3EditField.Value-1;
                    end 
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            
            key = event.Key;
            if key == "q" || key == "w" || key == "e" || key == "r"...
                    || key == "a" || key == "s"
                theta1 = string(app.Theta1EditField.Value+150);
                theta2 = string(app.Theta2EditField.Value+60);
                theta3 = string(app.Theta3EditField.Value+150);
                z = strcat('<', theta1, ", ", theta2, ", ", theta3, ">");
                writeline(app.s, z);
                
                q1 = pi/180*app.Theta1EditField.Value;
                q2 = pi/180*app.Theta2EditField.Value;
                q3 = pi/180*app.Theta3EditField.Value;
                
                %    theta       d        alpha        a
                T1 = rotZ(q1)*transZ(9)*rotX(pi/2)*transX(0);
                T2 = rotZ(q2)*transZ(0)*rotX(0)*transX(17);
                T3 = rotZ(q3)*transZ(0)*rotX(0)*transX(7.5);
                Tn = (T1*T2*T3)*[0 0 0 1]';
                
                app.XEditField.Value = Tn(1);
                app.YEditField.Value = Tn(2);
                app.ZEditField.Value = Tn(3);
                
                l1 = T1*[0 0 0 1]';
                l2 = T2*[0 0 0 1]';
                
                plot3(app.UIAxes,Tn(1), Tn(2), Tn(3), 'o')
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'End Effector')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MODEDropDownLabel = uilabel(app.UIFigure);
            app.MODEDropDownLabel.Text = 'MODE';
            app.MODEDropDown = uidropdown(app.UIFigure);
            app.MODEDropDown.Items = {'DGM', 'IGM', 'TRAJECTORIES', ''};
            app.MODEDropDown.Value = 'DGM';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.Limits = [-150 150];
            app.Theta1EditField.Value = 100;
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.Limits = [-150 150];
            app.Theta2EditField.Value = 100;
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.Limits = [-150 150];
            app.KeyQTheta1Label = uilabel(app.UIFigure);
            app.KeyQTheta1Label.Text = 'Key "Q"  Theta 1 +';
            app.KeyWTheta1Label = uilabel(app.UIFigure);
            app.KeyWTheta1Label.Text = 'Key "W"  Theta 1 -';
            app.KeyRTheta2Label = uilabel(app.UIFigure);
            app.KeyRTheta2Label.Text = 'Key "R"  Theta 2 -';
            app.KeyETheta2Label = uilabel(app.UIFigure);
            app.KeyETheta2Label.Text = 'Key "E"  Theta 2 +';
            app.KeyATheta3Label = uilabel(app.UIFigure);
            app.KeyATheta3Label.Text = 'Key "A"  Theta 3 +';
            app.KeySTheta3Label = uilabel(app.UIFigure);
            app.KeySTheta3Label.Text = 'Key "S"  Theta 3 -';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.2f';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.2f';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.2f';
            app.EndEffectorLabel = uilabel(app.UIFigure);
            app.EndEffectorLabel.Text = 'End Effector';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end